classdef miniBB2p_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StimulateFramesTextArea        matlab.ui.control.TextArea
        StimulateFramesLabel           matlab.ui.control.Label
        RecordFrameLengthEditField     matlab.ui.control.NumericEditField
        RecordFrameLengthEditFieldLabel  matlab.ui.control.Label
        OnlyDisplayEvenFramesCheckBox  matlab.ui.control.CheckBox
        ImageRangeDropDown             matlab.ui.control.DropDown
        ImageRangeDropDownLabel        matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        PixelsDropDown                 matlab.ui.control.DropDown
        PixelsDropDownLabel            matlab.ui.control.Label
        RecordButton                   matlab.ui.control.StateButton
        RefreshTimesEditField          matlab.ui.control.NumericEditField
        RefreshTimesEditFieldLabel     matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Record_Frame_Num % frames of the recorded video
        time % Ttimestamps used for naming file.
        Record_Or_Stop  % handle used for determining whether the "Record" button is pressed
        Stop % handle used for determining whether the "Stop" button is pressed
        Frame_Data % data of the single frame of the video
        Frame_Num % number of the frame
        Stimulate_Frame_Num % number of the frame when stimulating
        Stimulate_Frame_Num_Idx % index of the "Stimulate_Frame_Num:, i.e. counting the stimulating times
        Real_Time_Video % handle of the video displayed in the app
        Pixels_Text % pixel size of the video, string formatting
        Pixels_Value % pixel size of the video, number formatting
        Scale_Text % FOV of the video, string formatting
        Image_Scale_X % datascale parameter (0 to 1 value) of the MEMS along X axis scanning
        Image_Scale_Y % datascale parameter (0 to 1 value) of the MEMS along Y axis scanning
        mMTIDevice % handle of the MEMS controller
        cardInfo % handle of the Digitizer
        mRegs  % function library of the Digitizer
        mErrors % error of the Digitizer
        Even_Frames_Box_Value % handle used for Determining whether the "Only Display Even Frames" check box is selected
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initial setting of the video pixel size (defaulf 512x512)
            app.Pixels_Text=app.PixelsDropDown.Value;
            if app.Pixels_Text == "512x512"
                app.Pixels_Value = 512;
            elseif app.Pixels_Text == "256x256"
                app.Pixels_Value = 256;
            elseif app.Pixels_Text == "1536x1536"
                app.Pixels_Value = 1536;
            end

            %initial setting of the FOV (default 420x420um2)
            app.Scale_Text = app.ImageRangeDropDown.Value;%选择成像范围
            switch app.Scale_Text
                case "420x420um"
                    app.Image_Scale_X = 1;
                    app.Image_Scale_Y = 0.72;
                    % decrease the y-axis scanning range to chieve a nearly square FOV
                    % This value may be a little different for differet MEMS mirror
                case "120x120um"
                    app.Image_Scale_X = 0.48;
                    app.Image_Scale_Y = 0.195;
                    % the above value was measured for the MEMS mirror used in miniBB2p
                    % this value may be different for differet MEMS mirror
            end

            % initial setting of the "Start" button
            app.StartButton.Enable="on";
            app.StopButton.Enable="off";


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % "Start" button, used for starting the app

            %% initial setting of the buttons handles
            app.StartButton.Enable="off";
            app.PixelsDropDown.Enable="off";
            app.ImageRangeDropDown.Enable="off";
            app.StopButton.Enable="on";
            app.Stop = 0;
            app.Even_Frames_Box_Value = 0;

            %% MEMS setup
            % Create an object of the MTIDevice class to access its functions,
            % variables, and the target device at serial port
            path(path,'C:\MirrorcleTech\SDK-Matlab\MTIDeviceMatlab');
            app.mMTIDevice = MTIDevice;

            Controller_Sample_Rate = 92160;    % sample Rate of the MEMS controller
            t = linspace(0,1,Controller_Sample_Rate+1)';
            Xf = 2304;  % Frequncy of the fast axis, do not set near the resonant frequency
            Yf = Xf / app.Pixels_Value;    % Frequncy of the slow axis

            % Data can be negative and positive, representing negative and positive
            % deflections of the mirror for each axis
            % The data must be normalized to -1 to +1 floating point range
            x = app.Image_Scale_X*sin(2*Xf*pi.*t(1:end-1)-pi/2);  %   scanning start at (-1,-1) point, so add -pi/2 phase
            y = sawtooth(2*Yf*pi.*t(1:end-1),0.5);
            y = y(1 : (length(y)/Yf));

            % Setting software low-pass filter to the y axis
            SoftwareFilterBw = 1000;
            [bb,aa]=besself(6,SoftwareFilterBw*2*pi);
            % convert to z-domain (discrete time) based on sample rate sps
            [bin,ain]=impinvar(bb,aa,Controller_Sample_Rate);
            % make 3 copies and extract out middle after filter to have clean ends;
            % use filtfilt to avoid adding any group delay
            yf = filtfilt(bin,ain,[y;y;y]);
            yf = yf((1+1*length(yf)/3) : (2*length(yf)/3));
            y = yf;
            y = app.Image_Scale_Y*y;

            % FOV selection
            % Average_Parameter: To take full advantage of the sample rate
            % of the digitizer, each pixel value of the video was actually
            % averaged by the number of the "Average_Parameter" pixels
            switch app.Scale_Text
                case "420x420um"
                    switch app.Pixels_Value
                        case 512
                            Average_Parameter = 6;
                        case 256
                            Average_Parameter = 12;
                        case 1536
                            Average_Parameter = 2;
                    end

                    % Digital Output Control
                    % Within each half cycle of the x scaning (i.e. each scaning line for
                    % bidirection scanning), the digitizer and laser strated at the third point and paused at 19th point.
                    m=1*((x>(app.Image_Scale_X*(-0.96)))&(x<(app.Image_Scale_X*(0.96))));
                    m(19:20:end)=0; %19th point shoud be low level; used to control the AOM of the laser
                    %only output one y scanning cycle to controller
                    x=x(1:length(x)/Yf);
                    m=m(1:length(m)/Yf);

                case "120x120um"
                    switch app.Pixels_Value
                        case 512
                            Average_Parameter = 5;
                        case 256
                            Average_Parameter = 12;
                        case 1536
                            Average_Parameter = 2;
                    end
                    m=1*((x>(app.Image_Scale_X*(-0.81)))&(x<(app.Image_Scale_X*(0.81))));
                    m(17:20:end)=0;
                    x=x(1:length(x)/Yf);
                    m=m(1:length(m)/Yf);
            end


            try
                % Check for any available devices to connect to
                availableDevices = app.mMTIDevice.GetAvailableDevices();
                % Display the available devices and their ports
                app.mMTIDevice.ListAvailableDevices(availableDevices);
                % If no devices detected exit the demo
                if (availableDevices.NumDevices < 1)
                    return;
                end
                % If the user knows the comport can proceed this way
                comport = 'COM6'; % Serial COM port where the USB-SL Controller is connected
                % If the user does not know the port or has multiple devices attached
                %comport = cell2mat(availableDevices.CommPortName(1)); % Choose the first Controller found
                % Connect to USB-SL Controller at the specified comport
                app.mMTIDevice.ConnectDevice( comport );

                % Then we can check if connection is successful
                err = app.mMTIDevice.GetLastError();
                if (err ~= MTIError.MTI_SUCCESS)
                    disp('Failed to connect. Error returned is:');
                    disp(err);
                    app.mMTIDevice.DisconnectDevice();
                    delete(app.mMTIDevice);
                    return;
                end

                app.mMTIDevice.ResetDevicePosition();	% Send mirror to origin and zero offsets
                mParams = app.mMTIDevice.GetDeviceParams();	% Get device info and device parameters

                %check if RGB Capable module and if yes assign some color to the content
                if (( mParams.DataFormat == MTIDataFormat.Reduced_XYRGB )||( mParams.DataFormat == MTIDataFormat.Standard_XYRGB )||( mParams.DataFormat == MTIDataFormat.Standard_XYMRGB ))
                    rgbCapable = 1;
                    RGBColor = hex2dec('55DD44'); %color in hex, as RED(0-255) GREEN(0-255) BLUE(0-255)
                    app.mMTIDevice.SetRGBData( RGBColor );
                else
                    rgbCapable = 0;
                end

                % Now apply new device parameters as desired by user at beginning of the session
                % It is recommended to load the Mirrorcle-provided ini file for a
                % specific device and set those parameters
                lParams = app.mMTIDevice.LoadDeviceParams('mtidevice.ini');
                app.mMTIDevice.SetDeviceParams(lParams);

                % Any additional parameters can be set directly as follows:
                % This overrides any previously set parameters
                app.mMTIDevice.SetDeviceParam( MTIParam.DataScale, 1 );
                app.mMTIDevice.SetDeviceParam( MTIParam.DeviceAxes, MTIAxes.Normal );
                app.mMTIDevice.SetDeviceParam( MTIParam.OutputOffsets, 0, 0 );
                err = app.mMTIDevice.GetLastError();    % Optional, checking errors after a setting
                app.mMTIDevice.SetDeviceParam( MTIParam.SampleRate, Controller_Sample_Rate );
                app.mMTIDevice.SetDeviceParam( MTIParam.HardwareFilterBw, 50000 ); % !! Set the 50K to bypass the filter
                err = app.mMTIDevice.GetLastError();

                % Get API version info and key parameters to display for user
                version = app.mMTIDevice.GetAPIVersion();
                params = app.mMTIDevice.GetDeviceParams();
                DisplayVersionAndKeyParams();

            catch
                disp('Application failed to run properly!');
                app.mMTIDevice.ClearInputBuffer();
                app.mMTIDevice.DisconnectDevice();
                delete(app.mMTIDevice);
                disp('Closed in final catch..');
            end


            %% CARD setup
            % helper maps to use label names for registers and errors
            app.mRegs = spcMCreateRegMap ();
            app.mErrors = spcMCreateErrorMap ();

            % single card
            deviceString = '/dev/spcm0';

            [success, app.cardInfo] = spcMInitDevice (deviceString);

            if (success == true)
                % ----- print info about the board -----
                cardInfoText = spcMPrintCardInfo (app.cardInfo);
                fprintf (cardInfoText);
            else
                spcMErrorMessageStdOut (app.cardInfo, 'Error: Could not open card\n', true);
                return;
            end

            % ----- check whether we support this card type in the example -----
            if (app.cardInfo.cardFunction ~= app.mRegs('SPCM_TYPE_AI')) && (app.cardInfo.cardFunction ~= app.mRegs('SPCM_TYPE_DI'))
                spcMErrorMessageStdOut (app.cardInfo, 'Error: Card function not supported by this example\n', false);
                return;
            end

            % ----- check if Multiple Recording is installed -----
            if (bitand (app.cardInfo.featureMap, app.mRegs('SPCM_FEAT_MULTI')) == 0)
                spcMErrorMessageStdOut (app.cardInfo, 'Error: Multiple Recording Option not installed. Examples was done especially for this option!\n', false);
                return;
            else
                fprintf ('\n Multiple Recording ........ installed.');
            end

            % ----- check if timestamp is installed -----
            if (bitand (app.cardInfo.featureMap, app.mRegs('SPCM_FEAT_TIMESTAMP')) == 0)
                spcMErrorMessageStdOut (app.cardInfo, 'Error: Timestamp Option not installed. Examples was done especially for this option!\n', false);
                return;
            else
                fprintf ('\n Timestamp ................. installed.\n');
            end

            % ***** do card setup *****
            segmentSize = Average_Parameter * app.Pixels_Value+16;
            postsamples = Average_Parameter * app.Pixels_Value+8;

            % ----- set only 1 channel -----
            [success, app.cardInfo] = spcMSetupModeRecFIFOMulti (app.cardInfo, 0, 1, segmentSize, postsamples, 0);
            if (success == false)
                spcMErrorMessageStdOut (app.cardInfo, 'Error: spcMSetupModeRecFIFOMulti:\n\t', true);
                return;
            end


            % set sample rate of the digitizer
            % Sapmlerate of digitizer = 1/ (time period of gate openning (m=1) state t / (Pixels))

            % IMPORTANT!!
            % the Controller has internal delay of the Digital Output with respect to MEMS Output
            % Use "Trigger_Delay" for synchronization.
            % different settting, such as, FOV, pixel size, sample rate of the digitizer/MEMS controller (or maybe different hardware)
            % may lead to different Trigger_Delay, users need to adjust "Trigger_Delay" according to the real imaging
            % the values listed below were used in the miniBB2p
            switch app.Scale_Text
                case "420x420um"
                    digitizer_samplerate = 1 / (16 / Controller_Sample_Rate / (Average_Parameter * app.Pixels_Value - 1));
                    Trigger_Delay = 587;
                case "120x120um"
                    digitizer_samplerate = 1 / (12 / Controller_Sample_Rate / (Average_Parameter * app.Pixels_Value - 1));
                    Trigger_Delay = 622; 

            end


            [success, app.cardInfo] = spcMSetupClockPLL (app.cardInfo, digitizer_samplerate, 0);  % clock output : enable = 1, disable = 0
            if (success == false)
                spcMErrorMessageStdOut (app.cardInfo, 'Error: spcMSetupClockPLL:\n\t', true);
                return;
            end
            fprintf ('\n Sampling rate set to %.1f Hz\n', app.cardInfo.setSamplerate);

            errorCode = spcm_dwSetParam_i64 (app.cardInfo.hDrv, app.mRegs('SPC_TRIG_DELAY'), Trigger_Delay);


            % ----- set Trigger parameters -----

            pulsewidth = 0;
            singleSrc = 1;
            trigTerm = 1; %50 Ohm termination for external trigger signals
            extLine = 1;
            [success, app.cardInfo] = spcMSetupTrigExternal (app.cardInfo, app.mRegs('SPC_TM_POS'), trigTerm, pulsewidth, singleSrc, extLine);
            if (success == false)
                spcMErrorMessageStdOut (app.cardInfo, 'Error: spcMSetupTrigExternal:\n\t', true);
                return;
            end

            fprintf ('\n  !!! Using external trigger - please connect a signal to the trigger input !!!\n\n');

            % ----- program all input channels to 0/+1000 mV and 50 ohm termination (if it's available) -----
            for i=0 : app.cardInfo.maxChannels-1
                [success, app.cardInfo] = spcMSetupAnalogInputChannel (app.cardInfo, i, 500, 1, -100, 0);


                if (success == false)
                    spcMErrorMessageStdOut (app.cardInfo, 'Error: spcMSetupInputChannel:\n\t', true);
                    return;
                end
            end

            % ----- setup timestamp -----
            mode = bitor (app.mRegs('SPC_TSMODE_STARTRESET'), app.mRegs('SPC_TSCNT_INTERNAL'));
            [success, app.cardInfo] = spcMSetupTimestamp (app.cardInfo, mode, 0);
            if (success == false)
                spcMErrorMessageStdOut (app.cardInfo, 'Error: spcMSetupTimestamp:\n\t', true);
                return;
            end

            % ----- setup Triggerout at X0 -----
            errorCode = spcm_dwSetParam_i32 (app.cardInfo.hDrv, app.mRegs('SPCM_X0_MODE'), app.mRegs('SPCM_XMODE_ASYNCOUT'));

            % ----- buffer and notify sizes for data transfer -----
            notifySize = segmentSize * app.Pixels_Value;
            bufferSize = 102400 * notifySize;
            % ----- buffer and notify sizes for timestamps -----
            bufferSizeTS = 10240 * 1024 * 16; 
            notifySizeTS = 40960; 

            % ----- allocate data buffer memory -----
            fprintf ('\n allocate memory for FIFO transfer ... ');
            errorCode = spcm_dwSetupFIFOBuffer (app.cardInfo.hDrv, 0, 1, 1, app.cardInfo.bytesPerSample * bufferSize, app.cardInfo.bytesPerSample * notifySize);
            if (errorCode ~= 0)
                [success, app.cardInfo] = spcMCheckSetError (errorCode, app.cardInfo);
                spcMErrorMessageStdOut (app.cardInfo, 'spcm_dwSetupFIFOBuffer:\n\t', true);
                return;
            end

            % ----- allocate timestamps buffer memory -----
            errorCode = spcm_dwSetupFIFOBuffer (app.cardInfo.hDrv, 1, 1, 1, bufferSizeTS, notifySizeTS);

            if (errorCode ~= 0)
                [success, app.cardInfo] = spcMCheckSetError (errorCode, app.cardInfo);
                spcMErrorMessageStdOut (app.cardInfo, 'Error: spcm_dwSetupFIFOBuffer:\n\t', true);
                return;
            end

            fprintf ('ready.\n');

            % ----- set dataType: 0 = RAW (int16), 1 = Amplitude calculated (float) -----
            dataType = 1;

            % ----- set timeout -----
            errorCode = spcm_dwSetParam_i32 (app.cardInfo.hDrv, app.mRegs('SPC_TIMEOUT'), 10000);
            if (errorCode ~= 0)
                [success, app.cardInfo] = spcMCheckSetError (errorCode, app.cardInfo);
                spcMErrorMessageStdOut (app.cardInfo, 'Error: spcm_dwSetParam_i32:\n\t', true);
                return;
            end


            %% CARD start
            % ----- set command flags -----
            commandMask = bitor (app.mRegs('M2CMD_CARD_START'), app.mRegs('M2CMD_CARD_ENABLETRIGGER'));

            % ----- start card -----
            errorCode = spcm_dwSetParam_i32 (app.cardInfo.hDrv, app.mRegs('SPC_M2CMD'), commandMask);
            if (errorCode ~= 0)
                [success, app.cardInfo] = spcMCheckSetError (errorCode, app.cardInfo);
                spcMErrorMessageStdOut (app.cardInfo, 'spcm_dwSetParam_i32:\n\t', true);
                return;
            end

            pause(0.1);
            fprintf ('\n CARD Waiting for Trigger ! \n');


            %% MEMS start

            try
                % Turn MEMS driver on
                app.mMTIDevice.SetDeviceParam( MTIParam.MEMSDriverEnable, 1 );
                % Turn Digital Output on (optional)
                app.mMTIDevice.SetDeviceParam( MTIParam.DigitalOutputEnable, 1 );
                % Finally, optionally can check all the device params to see if set
                mParams = app.mMTIDevice.GetDeviceParams();

                % we set 90% scanning rage in y-axis for the safety
                % IMPORTANT !
                % x-axis scanning angle has much magnitude nearing the resonant frequency.
                % each MEMS mirror need to test the magnitude at diffrent
                % work frequencies by following the guidance of the MEMS mirror,
                % to make sure the scanning angel do not exceed the maximum of the MEMS.
                % a factor of 0.13 in the a-axis was used in our miniBB2p.
                app.mMTIDevice.GoToDevicePosition(0.13*x(1),0.9*y(1),0,100);
                app.mMTIDevice.StopDataStream();


                % Send data to Controller
                adjustDigitalOutputDelay = 0;  % Tells the function to adjust digital output for LPF group delays
                app.mMTIDevice.SendDataStream(0.13 * x, 0.9 * y, m, length(x), adjustDigitalOutputDelay, 0, 0); % Send data to Controller and run automatically
                app.mMTIDevice.StartDataStream(-1);  

            catch
                display('Application failed to run properly!');
                app.mMTIDevice.ClearInputBuffer();
                app.mMTIDevice.DisconnectDevice();
                delete(app.mMTIDevice);
                disp('Closed in final catch..');
            end
            fprintf ('\n Start scanning ! \n');


            %% Movie

            % initial setting of the parameters
            app.Frame_Num = 1;
            app.Record_Frame_Num = 1;
            Data_Index_Start = 1 + segmentSize - postsamples;
            app.Stimulate_Frame_Num_Idx = 1;
            app.Stimulate_Frame_Num = [];
            app.Frame_Data=zeros(app.Pixels_Value,app.Pixels_Value);
            Bitmap_Row_Data = zeros(1,app.Pixels_Value);
            app.Real_Time_Video=imagesc(app.UIAxes,app.Frame_Data); % video handle
            axis (app.UIAxes,'image');
            colormap (app.UIAxes,gray(256));
            colorbar(app.UIAxes,"TicksMode","auto");

            while(~app.Stop)

                start_time = tic;

                errorCode = spcm_dwSetParam_i32 (app.cardInfo.hDrv, app.mRegs('SPC_M2CMD'), app.mRegs('M2CMD_DATA_WAITDMA'));

                [errorCode, Dat_Block_Ch0] = spcm_dwGetData (app.cardInfo.hDrv, 0, notifySize/app.cardInfo.setChannels, app.cardInfo.setChannels, dataType);

                for Bitmap_Row_Idx = 1 : app.Pixels_Value

                    Data_Index = Data_Index_Start + (Bitmap_Row_Idx-1) * segmentSize;

                    switch mod(Bitmap_Row_Idx,2)
                        case 1
                            %Average each row data.
                            Bitmap_Row_Data = mean(reshape(Dat_Block_Ch0(Data_Index:Data_Index+postsamples-8-1),Average_Parameter,[]));
                            app.Frame_Data(Bitmap_Row_Idx,:) = Bitmap_Row_Data;
                        case 0
                            %Average each row data.
                            Bitmap_Row_Data = mean(reshape(Dat_Block_Ch0(Data_Index+1:Data_Index+postsamples-8),Average_Parameter,[]));
                            % the data need to be flip in the case of even rows.
                            app.Frame_Data(Bitmap_Row_Idx,:) = fliplr(Bitmap_Row_Data);
                    end
                end

                app.Frame_Data = app.Frame_Data - 0.005; % Remove SiPM dark offset

                if ~mod(app.Frame_Num,2)
                    app.Frame_Data = flipud(app.Frame_Data); % the data need to be flip in the case of even frames.
                end

                % can only display even frames, useful in the case of long-period recording.
                switch app.Even_Frames_Box_Value
                    case 0
                        set(app.UIAxes.Children,'CData',app.Frame_Data);
                        refreshdata(app.UIAxes, 'caller');
                        drawnow
                    case 1
                        if ~mod(app.Frame_Num,2)
                            set(app.UIAxes.Children,'CData',app.Frame_Data);
                            refreshdata(app.UIAxes, 'caller');
                            drawnow
                        end
                end

                % store data in terms of .h5
                if app.Record_Or_Stop==1 %Record State
                    h5write([app.time,'\Frame_Data.h5'],'/Data',app.Frame_Data,[1 1 app.Record_Frame_Num],[app.Pixels_Value app.Pixels_Value 1]);
                    app.Record_Frame_Num=app.Record_Frame_Num+1;
                    app.RecordFrameLengthEditField.Value=app.Record_Frame_Num;
                end

                end_time = toc (start_time);
                app.RefreshTimesEditField.Value=end_time;
                app.Frame_Num=app.Frame_Num+1;
            end
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            
            % "Record" button, used for save the data.
            app.Record_Or_Stop = app.RecordButton.Value;
            if app.Record_Or_Stop==1
                app.time = datestr(clock,'yyyy-mm-dd-HH-MM-SS');
                mkdir(app.time);

                %%save in terms of .h5
                h5create([app.time,'\Frame_Data.h5'],'/Data',[app.Pixels_Value app.Pixels_Value inf],'Chunksize',[app.Pixels_Value app.Pixels_Value 1],'Datatype','single');

                % ----- X0 Trigerout High -----
                errorCode = spcm_dwSetParam_i32 (app.cardInfo.hDrv, app.mRegs('SPCM_XX_ASYNCIO'), 1);

                %%save in terms of .dat
                %app.fIdCh0 = fopen([time,'\ch0.dat'], 'w');
                %app.RecordButton.Text="Pause";
            else
                %save app.Stimulate_Frame_Num in terms of .xls
                if ~isempty(app.Stimulate_Frame_Num)
                    writematrix(app.Stimulate_Frame_Num,[app.time,'\Stimulate_Frame.xls'])
                end
                %fclose(app.fIdCh0);
                app.time=[];
                app.Record_Frame_Num = 0;
                app.RecordButton.Text="Record";
                app.Frame_Num = 1;
                app.Record_Frame_Num = 1;
                app.Stimulate_Frame_Num_Idx = 1;
                app.Stimulate_Frame_Num = [];

                % ----- X0 Trigerout Low -----
                errorCode = spcm_dwSetParam_i32 (app.cardInfo.hDrv, app.mRegs('SPCM_XX_ASYNCIO'), 0);
            end


        end

        % Value changed function: PixelsDropDown
        function PixelsDropDownValueChanged(app, event)
            app.Pixels_Text = app.PixelsDropDown.Value;
            if app.Pixels_Text == "512x512"
                app.Pixels_Value = 512;
            elseif app.Pixels_Text == "256x256"
                app.Pixels_Value = 256;
            elseif app.Pixels_Text == "1536x1536"
                app.Pixels_Value = 1536;
            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            %% MEMS close
            app.Stop = 1;

            try
                app.mMTIDevice.StopDataStream();
                xStopPosition = app.mMTIDevice.GetXStop();
                yStopPosition = app.mMTIDevice.GetYStop();
                iStopPosition = app.mMTIDevice.GetIStop();
                fprintf('Mirror stopped by user.  Last sample position is %3.3f, %3.3f\n', xStopPosition, yStopPosition);
                fprintf('Mirror stopped by user.  Last sample index is %i\n\n', iStopPosition);
                %disp('Press any key to return reset device position to origin (with laser OFF)');
                %pause;
                app.mMTIDevice.ResetDevicePosition();


                % Return device to origin and close the session nicely
                app.mMTIDevice.ResetDevicePosition();
                % After the device is back to origin, disable the driver
                app.mMTIDevice.SetDeviceParam( MTIParam.MEMSDriverEnable, 0 );
                % Disconnect and delete the object
                app.mMTIDevice.DisconnectDevice();
                delete(app.mMTIDevice);
                display('Closed successfully..');

            catch
                display('Application failed to run properly!');
                app.mMTIDevice.ClearInputBuffer();
                app.mMTIDevice.DisconnectDevice();
                delete(app.mMTIDevice);
                display('Closed in final catch..');
            end


            %% CARD close
            % ----- stop card -----
            errorCode = spcm_dwSetParam_i32 (app.cardInfo.hDrv, app.mRegs('SPC_M2CMD'), app.mRegs('M2CMD_CARD_STOP'));

            % ***** free allocated buffer memory *****
            spcm_dwSetupFIFOBuffer (app.cardInfo.hDrv, 0, 0, 1, 0, 0);
            spcm_dwSetupFIFOBuffer (app.cardInfo.hDrv, 1, 0, 1, 0, 0);

            % ***** close card *****
            spcMCloseCard (app.cardInfo);

            app.StartButton.Enable="on";
            app.StopButton.Enable="off";
            app.PixelsDropDown.Enable="on";
            app.ImageRangeDropDown.Enable="on";
            app.OnlyDisplayEvenFramesCheckBox.Enable="on";


            evalin('base','clear variables');


        end

        % Value changed function: ImageRangeDropDown
        function ImageRangeDropDownValueChanged(app, event)
            app.Scale_Text = app.ImageRangeDropDown.Value;
            switch app.Scale_Text
                case "420x420um"
                    app.Image_Scale_X = 1;
                    app.Image_Scale_Y = 0.72;
                case "120x120um"
                    app.Image_Scale_X = 0.42;
                    app.Image_Scale_Y = 0.22;
            end

        end

        % Value changed function: OnlyDisplayEvenFramesCheckBox
        function OnlyDisplayEvenFramesCheckBoxValueChanged(app, event)
            app.Even_Frames_Box_Value = app.OnlyDisplayEvenFramesCheckBox.Value;
            
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            % used to record the frame when stimulating the mice.
            % press C when stimulating.
            % we use this value, together with the camera video, to determine the frame of stimulating
            % Only use when "Record" button pressed.
            key = event.Key;
            switch key
                case 'c'
                    app.Stimulate_Frame_Num(app.Stimulate_Frame_Num_Idx) = app.Record_Frame_Num;
                    app.Stimulate_Frame_Num_Idx = app.Stimulate_Frame_Num_Idx + 1;
                    app.StimulateFramesTextArea.Value = num2str(app.Stimulate_Frame_Num);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RefreshTimesEditFieldLabel = uilabel(app.UIFigure);
            app.RefreshTimesEditFieldLabel.Text = 'Refresh Time(s)';
            app.RefreshTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecordButton = uibutton(app.UIFigure, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Record';
            app.PixelsDropDownLabel = uilabel(app.UIFigure);
            app.PixelsDropDownLabel.Text = 'Pixels';
            app.PixelsDropDown = uidropdown(app.UIFigure);
            app.PixelsDropDown.Items = {'512x512', '1536x1536', '256x256'};
            app.PixelsDropDown.ValueChangedFcn = createCallbackFcn(app, @PixelsDropDownValueChanged, true);
            app.PixelsDropDown.Value = '512x512';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ImageRangeDropDownLabel = uilabel(app.UIFigure);
            app.ImageRangeDropDownLabel.Text = 'Image Range';
            app.ImageRangeDropDown = uidropdown(app.UIFigure);
            app.ImageRangeDropDown.Items = {'420x420um', '120x120um'};
            app.ImageRangeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageRangeDropDownValueChanged, true);
            app.ImageRangeDropDown.Value = '420x420um';
            app.OnlyDisplayEvenFramesCheckBox = uicheckbox(app.UIFigure);
            app.OnlyDisplayEvenFramesCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnlyDisplayEvenFramesCheckBoxValueChanged, true);
            app.OnlyDisplayEvenFramesCheckBox.Text = 'Only Display Even Frames';
            app.RecordFrameLengthEditFieldLabel = uilabel(app.UIFigure);
            app.RecordFrameLengthEditFieldLabel.Text = 'Record Frame Length';
            app.RecordFrameLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.StimulateFramesLabel = uilabel(app.UIFigure);
            app.StimulateFramesLabel.Text = 'Stimulate Frames';
            app.StimulateFramesTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = miniBB2p_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end